classdef AP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AeropendulumControlPanelUIFigure  matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        PortEditFieldLabel      matlab.ui.control.Label
        PortEditField           matlab.ui.control.EditField
        ConnectButton           matlab.ui.control.Button
        PIDSettingsLabel        matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        PEditFieldLabel         matlab.ui.control.Label
        PEditField              matlab.ui.control.NumericEditField
        IEditFieldLabel         matlab.ui.control.Label
        IEditField              matlab.ui.control.NumericEditField
        DEditFieldLabel         matlab.ui.control.Label
        DEditField              matlab.ui.control.NumericEditField
        SetNewLabel             matlab.ui.control.Label
        SendnewPIDButton        matlab.ui.control.Button
        BaudRateEditFieldLabel  matlab.ui.control.Label
        BaudRateEditField       matlab.ui.control.NumericEditField
        StartStopButton         matlab.ui.control.StateButton
        ConsoleListBoxLabel     matlab.ui.control.Label
        ConsoleListBox          matlab.ui.control.ListBox
        SignalLabel             matlab.ui.control.Label
        TargetEditFieldLabel    matlab.ui.control.Label
        TargetEditField         matlab.ui.control.NumericEditField
        SetButton               matlab.ui.control.Button
        Label_2                 matlab.ui.control.Label
    end

    
    properties (Access = public) %PUBLIC VARIABLES! 
        %Serial properties
        s % Serial object
        
        %Graphic preferences
        LineNpts=500 %Number of points displayed on the graph
        
        %%Inner workings
        ConsoleArrayText %Text variable for the console
        %smsg %Serial message split on a cell array {index}
        i=1 %Console Index
        
    end

    methods (Access = private) %FUNCTIONS
    
        function SendToArduino(app,FC,msgConsole) %Sends all fields to the arduino checksum should be checked later (spot marked few lines ahead)
                try
                   fwrite(app.s,['#',FC,'#',num2str(app.TargetEditField.Value/180*pi),'#',num2str(app.PEditField.Value),'#',num2str(app.IEditField.Value),'#',num2str(app.DEditField.Value)]);
                   app.SndToConsole(msgConsole);
                catch
                   app.SndToConsole(['Connection error! Please check if the device is connected and available on port:',app.PortEditField.Value]);
                end
        end
        
        function SndToConsole(app,cout)
                app.ConsoleArrayText{app.i}=sprintf('%s\n%s',cout);
                app.ConsoleListBox.Items = app.ConsoleArrayText;
                app.i=app.i+1;
                scroll(app.ConsoleListBox,'bottom'); %Autoscroll to the bottom
        end
        
    end

    %%CALLBACKS AHEAD

    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if app.StartStopButton.Value==1 %Stop
                app.StartStopButton.Value=0
            end
            try
                fclose(app.s);
                cla(app.UIAxes); %Clear Graph
            end
            app.Lamp.Color='r';
            %app.ConsoleArrayText={}; %Clear Console
            app.s=serial(app.PortEditField.Value,'BaudRate',app.BaudRateEditField.Value);
            
            app.SndToConsole(['Creating serial object at: ', app.PortEditField.Value,' ', 'Baud Rate: ', int2str(app.BaudRateEditField.Value)]);
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            value = app.StartStopButton.Value;
            
            try
                fopen(app.s);
            catch
                disp('Error opening the serial port');
            end
            
            %GUI STUFF AND CHECKS
            if app.StartStopButton.Value==1
                app.Lamp.Color='g';
                
                app.SndToConsole('Start');
                
                cla(app.UIAxes); %Clear graph
                
                %Sending Start signal to the arduino (have to send pid's)
                app.SendToArduino('1',['Current Signal and PID values are: ','Signal:',num2str(app.TargetEditField.Value/180*pi),' P:',num2str(app.PEditField.Value),' I:',num2str(app.IEditField.Value),' D:',num2str(app.DEditField.Value)]);
            else
                app.Lamp.Color='r';
                
                app.SndToConsole('Stopped')
                %Should add a way to save data
                
                %Sending Stop signal to the arduino
                app.SendToArduino('0','Done');

            end   
            
            %app.s %For debug purposes
            
            %Setting up graphics
            app.UIAxes.XLim=[-Inf,Inf];
            app.UIAxes.YLim=[-1,160];
            h1 = animatedline('MaximumNumPoints',app.LineNpts,'Color','r','Parent', app.UIAxes);
            h2 = animatedline('MaximumNumPoints',app.LineNpts,'Color','b','Parent', app.UIAxes);
            h3 = animatedline('MaximumNumPoints',app.LineNpts,'Color','g','Parent', app.UIAxes);
            tic;
            
            %GRAPHICS UPDATE LOOP
            while(app.StartStopButton.Value==1)
                 %Read Serial
                 try %Avoid reading errors
                    msg=fscanf(app.s);
                    %msg %Debug
                 catch
                     disp 'Error reading serial data'
                     msg='00|00|00|00|00|00|00'; %If there are changes to the default message change this
                 end
                 if contains(msg,'#') % Insert checksum here then resend PID values if something went wrong
                     continue;
                 end
                 if isempty(msg)
                     continue;
                 end

                 
                 %Plot read data
                 smsg=split(msg,'|');
    	         addpoints(h1,toc,str2double(smsg{1})*180/pi); %angle
                 addpoints(h2,toc,app.TargetEditField.Value); %Setpoint
                                     
                 %Show motor siganl
                 %try
                 %    app.Label_2.Text=[smsg{2},' .us'];
                 %end
    	 	 
                 %addpoints(h2,toc,str2double(smsg{2})); %MS
    	 	 %addpoints(h3,toc,str2double(smsg{3})); %controller.inst
                 %Plot the data
                 drawnow limitrate;
                 
                 %app.i=app.i+1;
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.SendToArduino(num2str(app.StartStopButton.Value),['Current Signal and PID values are: ','Signal:',num2str(app.TargetEditField.Value/180*pi),' P:',num2str(app.PEditField.Value),' I:',num2str(app.IEditField.Value),' D:',num2str(app.DEditField.Value)]);
        end

        % Button pushed function: SendnewPIDButton
        function SendnewPIDButtonPushed(app, event)
           app.SendToArduino(num2str(app.StartStopButton.Value),['Current Signal and PID values are: ','Signal:',num2str(app.TargetEditField.Value/180*pi),' P:',num2str(app.PEditField.Value),' I:',num2str(app.IEditField.Value),' D:',num2str(app.DEditField.Value)]); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AeropendulumControlPanelUIFigure = uifigure;
            app.AeropendulumControlPanelUIFigure.Name = 'Aeropendulum: Control Panel';
            app.UIAxes = uiaxes(app.AeropendulumControlPanelUIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Angle')
            app.PortEditFieldLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.AeropendulumControlPanelUIFigure, 'text');
            app.PortEditField.Value = '/dev/ttyUSB0';
            app.ConnectButton = uibutton(app.AeropendulumControlPanelUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PIDSettingsLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.PIDSettingsLabel.Text = 'PID Settings';
            app.Label = uilabel(app.AeropendulumControlPanelUIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.AeropendulumControlPanelUIFigure);
            app.PEditFieldLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.AeropendulumControlPanelUIFigure, 'numeric');
            app.PEditField.Value = 0.03;
            app.IEditFieldLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.AeropendulumControlPanelUIFigure, 'numeric');
            app.IEditField.Value = 0.06;
            app.DEditFieldLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.AeropendulumControlPanelUIFigure, 'numeric');
            app.SetNewLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.SetNewLabel.Text = 'Set New';
            app.SendnewPIDButton = uibutton(app.AeropendulumControlPanelUIFigure, 'push');
            app.SendnewPIDButton.ButtonPushedFcn = createCallbackFcn(app, @SendnewPIDButtonPushed, true);
            app.SendnewPIDButton.Text = 'Send new PID';
            app.BaudRateEditFieldLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.AeropendulumControlPanelUIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.StartStopButton = uibutton(app.AeropendulumControlPanelUIFigure, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.ConsoleListBoxLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.ConsoleListBoxLabel.Text = 'Console';
            app.ConsoleListBox = uilistbox(app.AeropendulumControlPanelUIFigure);
            app.ConsoleListBox.Items = {'Select a port and click connect'};
            app.ConsoleListBox.Value = 'Select a port and click connect';
            app.SignalLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.SignalLabel.Text = 'Signal';
            app.TargetEditFieldLabel = uilabel(app.AeropendulumControlPanelUIFigure);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.AeropendulumControlPanelUIFigure, 'numeric');
            app.TargetEditField.Limits = [0 120];
            app.TargetEditField.Value = 90;
            app.SetButton = uibutton(app.AeropendulumControlPanelUIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Label_2 = uilabel(app.AeropendulumControlPanelUIFigure);
            app.Label_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AP_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AeropendulumControlPanelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AeropendulumControlPanelUIFigure)
        end
    end
end